classdef Uzivatel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRAUIFigure       matlab.ui.Figure
        NapovedaCheckBox  matlab.ui.control.CheckBox
        MTrButton         matlab.ui.control.Button
        MKrButton         matlab.ui.control.Button
        MCtButton         matlab.ui.control.Button
        MHvButton         matlab.ui.control.Button
        CTrButton         matlab.ui.control.Button
        CKrButton         matlab.ui.control.Button
        CCtButton         matlab.ui.control.Button
        CHvButton         matlab.ui.control.Button
        Button_1          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        Button_13         matlab.ui.control.Button
        Button_14         matlab.ui.control.Button
        Button_15         matlab.ui.control.Button
        Button_16         matlab.ui.control.Button
        STARTButton       matlab.ui.control.Button
        KONECButton       matlab.ui.control.Button
    end

   
    properties (Constant, Access = private)
        R          = Radic.getInstance();
        svetleSeda = [0.9 , 0.9 , 0.9 ];
        tmaveSeda  = [0.65, 0.65, 0.65];
    end %prop2
    
    methods (Access = private)
        
        % Pomocna metoda pro stisknuti tlacitek tvaru
        function out = pomTl(app, ikona, x, y)
            app.R.ulozPozici(ikona, x, y)
            stav = app.R.getStav();
            if stav == "pokracuj"
                ikona = app.R.getIkona();
            else
                ikona = app.R.vratPuvodniIkonu();
            end %if
            out = ikona;
        end %fc
        
        % Pomocna metoda, ktera rozsviti volna pole zelene v pripade
        % zaskrtnuti okenka napovedy
        function vyhodnotNapovedu(app)
            stav = app.R.vratPrubehHry();
            info = app.R.jeNaTahuHrac ();
            value = app.NapovedaCheckBox.Value;
            app.barvaZpet();         
            if stav == "konec" || info == "stop" || value == 0
                return              %==========>
            end %if
        
            vektor = app.R.vratVolnaPole();
            for k = vektor
                tlacitko = app.vratTlacitko(k);
                tlacitko.BackgroundColor = [0 1 0];          
            end %for
             
        end %fc
        
        % Pomocna metoda vracejici tlacitko podle indexu umisteni
        function tlacitko = vratTlacitko(app, ind)
            switch ind
                case 1
                    tlacitko = app.Button_1;
                case 2
                    tlacitko = app.Button_2;
                case 3
                    tlacitko = app.Button_3;
                case 4
                    tlacitko = app.Button_4;
                case 5
                    tlacitko = app.Button_5;
                case 6
                    tlacitko = app.Button_6;
                case 7
                    tlacitko = app.Button_7;
                case 8
                    tlacitko = app.Button_8;
                case 9
                    tlacitko = app.Button_9;
                case 10
                    tlacitko = app.Button_10;
                case 11
                    tlacitko = app.Button_11;
                case 12
                    tlacitko = app.Button_12;
                case 13
                    tlacitko = app.Button_13;
                case 14
                    tlacitko = app.Button_14;
                case 15
                    tlacitko = app.Button_15;
                case 16
                    tlacitko = app.Button_16;
            end %switch
            
        end %fc
        
        % Pomocna metoda vracejici zpet puvodni barvu hracimu poli
        function barvaZpet(app)
            sv = [1 2 5 6 11 12 15 16];
            for i = 1:16
                tlacitko                 = app.vratTlacitko(i);
                tlacitko.BackgroundColor = app.tmaveSeda;
            end %for1
            
            for i = sv
                tlacitko = app.vratTlacitko(i);
                tlacitko.BackgroundColor = app.svetleSeda;
            end %for2
        end %fc
    end %methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MTrButton
        function MTrButtonPushed(app, event)
            cislo = 1;
            ikona = app.MTrButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            ikona = app.Button_1.Icon;
            x = 1;
            y = 1;
            ikona = pomTl(app,ikona, x, y);
            app.Button_1.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            ikona = app.STARTButton.Icon;
            app.R.startKonec("prubeh");
            app.Button_1.Icon  = ikona;
            app.Button_2.Icon  = ikona;
            app.Button_3.Icon  = ikona;
            app.Button_4.Icon  = ikona;
            app.Button_5.Icon  = ikona;
            app.Button_6.Icon  = ikona;
            app.Button_7.Icon  = ikona;
            app.Button_8.Icon  = ikona;
            app.Button_9.Icon  = ikona;
            app.Button_10.Icon = ikona;
            app.Button_11.Icon = ikona;
            app.Button_12.Icon = ikona;
            app.Button_13.Icon = ikona;
            app.Button_14.Icon = ikona;
            app.Button_15.Icon = ikona;
            app.Button_16.Icon = ikona;
            msgbox("Po potvrzeni tohoto okna zacina modry hrac."...
                    + newline + "Hraci se po kazdem tahu stridaji."...
                    + newline + "Nejprve kliknete na vybrany tvar a pote"...
                    + " na policko, kam chcete tvar umistit."...
                    + newline + "Stisknutim tlacitka START pokazde zacnete novou hru."...
                    + newline + "Stisknutim tlacitka KONEC zavrete aplikaci."...
                    + newline + "Zaskrtnutim okenka Napoveda se vam po vybrani tvaru"...
                    + " vybarvi zelene volna policka, kam lze tvar umistit.",...
                    "Informace", "help");
            app.barvaZpet();
        end

        % Button pushed function: MKrButton
        function MKrButtonPushed(app, event)
            cislo = 2;
            ikona = app.MKrButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: MCtButton
        function MCtButtonPushed(app, event)
            cislo = 3;
            ikona = app.MCtButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: MHvButton
        function MHvButtonPushed(app, event)
            cislo = 4;
            ikona = app.MHvButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: CTrButton
        function CTrButtonPushed(app, event)
            cislo = 11;
            ikona = app.CTrButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: CKrButton
        function CKrButtonPushed(app, event)
            cislo = 22;
            ikona = app.CKrButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: CCtButton
        function CCtButtonPushed(app, event)
            cislo = 33;
            ikona = app.CCtButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: CHvButton
        function CHvButtonPushed(app, event)
            cislo = 44;
            ikona = app.CHvButton.Icon;
            app.R.ulozTvar(ikona, cislo);
            app.vyhodnotNapovedu();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            ikona = app.Button_2.Icon;
            x = 1;
            y = 2;
            ikona = pomTl(app, ikona, x, y);
            app.Button_2.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            ikona = app.Button_3.Icon;
            x = 1;
            y = 3;
            ikona = pomTl(app, ikona, x, y);
            app.Button_3.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            ikona = app.Button_4.Icon;
            x = 1;
            y = 4;
            ikona = pomTl(app, ikona, x, y);
            app.Button_4.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            ikona = app.Button_5.Icon;
            x = 2;
            y = 1;
            ikona = pomTl(app, ikona, x, y);
            app.Button_5.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            ikona = app.Button_6.Icon;
            x = 2;
            y = 2;
            ikona = pomTl(app, ikona, x, y);
            app.Button_6.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            ikona = app.Button_7.Icon;
            x = 2;
            y = 3;
            ikona = pomTl(app, ikona, x, y);
            app.Button_7.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            ikona = app.Button_8.Icon;
            x = 2;
            y = 4;
            ikona = pomTl(app, ikona, x, y);
            app.Button_8.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            ikona = app.Button_9.Icon;
            x = 3;
            y = 1;
            ikona = pomTl(app, ikona, x, y);
            app.Button_9.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            ikona = app.Button_10.Icon;
            x = 3;
            y = 2;
            ikona = pomTl(app, ikona, x, y);
            app.Button_10.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            ikona = app.Button_11.Icon;
            x = 3;
            y = 3;
            ikona = pomTl(app, ikona, x, y);
            app.Button_11.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            ikona = app.Button_12.Icon;
            x = 3;
            y = 4;
            ikona = pomTl(app, ikona, x, y);
            app.Button_12.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            ikona = app.Button_13.Icon;
            x = 4;
            y = 1;
            ikona = pomTl(app, ikona, x, y);
            app.Button_13.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            ikona = app.Button_14.Icon;
            x = 4;
            y = 2;
            ikona = pomTl(app, ikona, x, y);
            app.Button_14.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            ikona = app.Button_15.Icon;
            x = 4;
            y = 3;
            ikona = pomTl(app, ikona, x, y);
            app.Button_15.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            ikona = app.Button_16.Icon;
            x = 4;
            y = 4;
            ikona = pomTl(app, ikona, x, y);
            app.Button_16.Icon = ikona;
            app.barvaZpet();
        end

        % Button pushed function: KONECButton
        function KONECButtonPushed(app, event)
            app.R.startKonec("konec")
            app.delete();
        end

        % Value changed function: NapovedaCheckBox
        function NapovedaCheckBoxValueChanged(app, event)
            value = app.NapovedaCheckBox.Value;
            if value == 0
                app.vyhodnotNapovedu();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HRAUIFigure = uifigure('Visible', 'off');
            app.HRAUIFigure.Name = 'HRA';
            app.KONECButton = uibutton(app.HRAUIFigure, 'push');
            app.KONECButton.ButtonPushedFcn = createCallbackFcn(app, @KONECButtonPushed, true);
            app.KONECButton.Text = 'KONEC';
            app.STARTButton = uibutton(app.HRAUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Button_16 = uibutton(app.HRAUIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Tag = '16';
            app.Button_16.Text = '';
            app.Button_15 = uibutton(app.HRAUIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Tag = '15';
            app.Button_15.Text = '';
            app.Button_14 = uibutton(app.HRAUIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Tag = '14';
            app.Button_14.Text = '';
            app.Button_13 = uibutton(app.HRAUIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Tag = '13';
            app.Button_13.Text = '';
            app.Button_12 = uibutton(app.HRAUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Tag = '12';
            app.Button_12.Text = '';
            app.Button_11 = uibutton(app.HRAUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Tag = '11';
            app.Button_11.Text = '';
            app.Button_10 = uibutton(app.HRAUIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Tag = '10';
            app.Button_10.Text = '';
            app.Button_9 = uibutton(app.HRAUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Tag = '9';
            app.Button_9.Text = '';
            app.Button_8 = uibutton(app.HRAUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tag = '8';
            app.Button_8.Text = '';
            app.Button_7 = uibutton(app.HRAUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Tag = '7';
            app.Button_7.Text = '';
            app.Button_6 = uibutton(app.HRAUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = '6';
            app.Button_6.Text = '';
            app.Button_5 = uibutton(app.HRAUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = '5';
            app.Button_5.Text = '';
            app.Button_4 = uibutton(app.HRAUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = '4';
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.HRAUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tag = '3';
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.HRAUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = '2';
            app.Button_2.Text = '';
            app.Button_1 = uibutton(app.HRAUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Tag = '1';
            app.Button_1.Text = '';
            app.CHvButton = uibutton(app.HRAUIFigure, 'push');
            app.CHvButton.ButtonPushedFcn = createCallbackFcn(app, @CHvButtonPushed, true);
            app.CHvButton.Tag = '44';
            app.CHvButton.Icon = 'cervena_hvezda1.png';
            app.CHvButton.Text = '';
            app.CCtButton = uibutton(app.HRAUIFigure, 'push');
            app.CCtButton.ButtonPushedFcn = createCallbackFcn(app, @CCtButtonPushed, true);
            app.CCtButton.Tag = '33';
            app.CCtButton.Icon = 'cerveny_ctverec1.png';
            app.CCtButton.Text = '';
            app.CKrButton = uibutton(app.HRAUIFigure, 'push');
            app.CKrButton.ButtonPushedFcn = createCallbackFcn(app, @CKrButtonPushed, true);
            app.CKrButton.Tag = '22';
            app.CKrButton.Icon = 'cerveny_kruh1.png';
            app.CKrButton.Text = '';
            app.CTrButton = uibutton(app.HRAUIFigure, 'push');
            app.CTrButton.ButtonPushedFcn = createCallbackFcn(app, @CTrButtonPushed, true);
            app.CTrButton.Tag = '11';
            app.CTrButton.Icon = 'cerveny_trojuhelnik1.png';
            app.CTrButton.Text = '';
            app.MHvButton = uibutton(app.HRAUIFigure, 'push');
            app.MHvButton.ButtonPushedFcn = createCallbackFcn(app, @MHvButtonPushed, true);
            app.MHvButton.Tag = '4';
            app.MHvButton.Icon = 'modra_hvezda1.png';
            app.MHvButton.Text = '';
            app.MCtButton = uibutton(app.HRAUIFigure, 'push');
            app.MCtButton.ButtonPushedFcn = createCallbackFcn(app, @MCtButtonPushed, true);
            app.MCtButton.Tag = '3';
            app.MCtButton.Icon = 'modry_ctverec1.png';
            app.MCtButton.Text = '';
            app.MKrButton = uibutton(app.HRAUIFigure, 'push');
            app.MKrButton.ButtonPushedFcn = createCallbackFcn(app, @MKrButtonPushed, true);
            app.MKrButton.Tag = '2';
            app.MKrButton.Icon = 'modry_kurh1.png';
            app.MKrButton.Text = '';
            app.MTrButton = uibutton(app.HRAUIFigure, 'push');
            app.MTrButton.ButtonPushedFcn = createCallbackFcn(app, @MTrButtonPushed, true);
            app.MTrButton.Tag = '1';
            app.MTrButton.Icon = 'modry_trojuhelnik1.png';
            app.MTrButton.Text = '';
            app.NapovedaCheckBox = uicheckbox(app.HRAUIFigure);
            app.NapovedaCheckBox.ValueChangedFcn = createCallbackFcn(app, @NapovedaCheckBoxValueChanged, true);
            app.NapovedaCheckBox.Text = 'Napoveda';
            % Show the figure after all components are created
            app.HRAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Uzivatel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRAUIFigure)
        end
    end
end